classdef dynoControllerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        kVValueEditField              matlab.ui.control.NumericEditField
        kVValueEditFieldLabel         matlab.ui.control.Label
        RValueEditField               matlab.ui.control.NumericEditField
        RValueEditFieldLabel          matlab.ui.control.Label
        MotorSelectionDropDown        matlab.ui.control.DropDown
        MotorSelectionDropDownLabel   matlab.ui.control.Label
        EmergencyMotorStopButton      matlab.ui.control.Button
        MotorSwitch                   matlab.ui.control.Switch
        MotorStatusLamp               matlab.ui.control.Lamp
        MotorStatusLampLabel          matlab.ui.control.Label
        TemperatureMonitoringLabel_2  matlab.ui.control.Label
        TemperatureTable              matlab.ui.control.Table
        StartButton                   matlab.ui.control.StateButton
        MotorControlLabel             matlab.ui.control.Label
        MeasuredTorqueinlbEditField   matlab.ui.control.NumericEditField
        MeasuredTorqueinlbEditFieldLabel  matlab.ui.control.Label
        MeasuredRotationalSpeedRPMEditField  matlab.ui.control.NumericEditField
        SaveDataButton                matlab.ui.control.Button
        MeasuredRotationalSpeedRPMEditFieldLabel  matlab.ui.control.Label
        DataCollectionLabel           matlab.ui.control.Label
        SetTorqueinlbSpinner          matlab.ui.control.Spinner
        SetTorqueinlbSpinnerLabel     matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        SetRotationalSpeedRPMSpinner  matlab.ui.control.Spinner
        SetRotationalSpeedRPMSpinnerLabel  matlab.ui.control.Label
        RotationalSpeedPlot           matlab.ui.control.UIAxes
        TorquePlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        a; % arduino variable
        count; % counter

        liveTimerRot; % realtime variable for rotational speed
        rotPlot; % rotational speed plot
        rotYdata; % current rotational speed

        liveTimerTor; % realtime variable for torque
        torPlot; % torque plot
        torYdata; % current torque

        startButtonVal; % checker for start button
        desiredRPMvalue; % desired RPM value
        desiredTorquevalue; % desired RPM value

        motorOnVal; % checker to prime motor

        motorProp; % motor properties

        currMotorR; % Current Selected Motor: R Value
        currMotorkV; % Current Selected Motor: kV Value
        motorVals; % Values of R and kV to display in char cell array

        liveTimerTemp; % realtime temperature monitoring timer
        currTempVals; % current temperature on device
        safeTempVals; % safe temperature values
        headingLocation; % heading locations
    end

    methods (Access = private)

        function rotTimerFcn(app,~,~)
            % Add data here
            data = rand * 10; %Pulling data
            app.count = app.count + 1; %Time variable incremented
            app.MeasuredRotationalSpeedRPMEditField.Value = data;

            % Update YData in plot
            app.rotYdata = app.rotPlot.YData;
            app.rotYdata = circshift(app.rotYdata,1);
            app.rotYdata(1) = data;
            app.rotPlot.YData = app.rotYdata;

        end
        
        function torTimerFcn(app,~,~)
            % Add data here
            data = rand * 10; %Pulling data
            app.MeasuredTorqueinlbEditField.Value = data;

            % Update YData in plot
            app.torYdata = app.torPlot.YData;
            app.torYdata = circshift(app.torYdata,1);
            app.torYdata(1) = data;
            app.torPlot.YData = app.torYdata;

        end

        function tempTimerFcn(app,~,~)
            % Add data here
            tempOne = rand * 100;
            tempTwo = rand * 100;
            tempThree = rand * 100;
            tempFour = rand * 100;
            app.currTempVals = {tempOne, tempTwo, tempThree, tempFour}';
            app.TemperatureTable.Data = table(app.headingLocation, app.currTempVals, app.safeTempVals);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");
            %app.a = arduino('COM3','Nano33BLE');
            app.count = 0; % inital time value

            % Initial Live Plots
            app.rotPlot = plot(app.RotationalSpeedPlot, 0:60,zeros(1,61));
            app.torPlot = plot(app.TorquePlot, 0:60,zeros(1,61));

            % Timer object for rotional speed plot
            app.liveTimerRot = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.rotTimerFcn);          % Specify callback function

            % Timer object for torque plot
            app.liveTimerTor = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.torTimerFcn);          % Specify callback function

            % Timer object for temperature monitoring
             app.liveTimerTemp = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 3, ...                     % Period is 3 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.tempTimerFcn);          % Specify callback function
            

            % Temperature Monitoring Table Setup
            app.currTempVals = {0, 0, 0, 0}'; % add real time temp values here
            app.safeTempVals = {75, 55, 50, 45}'; % add safe temperatures values here
            app.headingLocation = {'Hysteresis Brake', 'Motor', 'Electronic Components', 'Structure'}'; % add thermocouple locations here
            app.TemperatureTable.Data = table(app.headingLocation, app.currTempVals, app.safeTempVals);
            start(app.liveTimerTemp);

            % Spinner Settings
            app.SetRotationalSpeedRPMSpinner.Step = 100;
            app.SetRotationalSpeedRPMSpinner.Limits = [0 Inf];
            app.SetTorqueinlbSpinner.Step = 1;
            app.SetTorqueinlbSpinner.Limits = [0 Inf];
            
            % Motor Priming Settings
            app.MotorStatusLamp.Color = 'red';

            % Reading in excel file data
            app.motorProp = readtable('MotorProperties.xlsx'); % File must be named this and saved to same folder
        
            motorNameCell = table2cell(app.motorProp(:,1)); 
            app.currMotorR = table2array(app.motorProp(:,6));
            app.currMotorkV = table2array(app.motorProp(:,5));
            app.motorVals = {};
            
            % Adding R and kV Values to drop down menu
            for i = 1:numel(app.currMotorR)
                app.motorVals{i} = {app.currMotorR(i,1), app.currMotorkV(i,1)};
            end

            app.MotorSelectionDropDown.Items = motorNameCell';

            app.MotorSelectionDropDown.ItemsData = app.motorVals;
          
            % Updating inital Value of R and kV
            app.RValueEditField.Value = app.MotorSelectionDropDown.Value{1};
            app.kVValueEditField.Value = app.MotorSelectionDropDown.Value{2};
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StartButton.Value = 0;
            stop(app.liveTimerRot);
            stop(app.liveTimerTor);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            app.startButtonVal = app.StartButton.Value;

            % If timer is not running, start it
            if strcmp(app.liveTimerRot.Running, 'off')
               start(app.liveTimerRot);
            end

            if strcmp(app.liveTimerTor.Running, 'off')
               start(app.liveTimerTor);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.liveTimerRot);
            delete(app.liveTimerRot);

            stop(app.liveTimerTor);
            delete(app.liveTimerTor);
            
            stop(app.liveTimerTemp);
            delete(app.liveTimerTemp);
            delete(app)
        end

        % Value changed function: SetRotationalSpeedRPMSpinner
        function SetRotationalSpeedRPMSpinnerValueChanged(app, event)
            app.desiredRPMvalue = app.SetRotationalSpeedRPMSpinner.Value;          
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % If timer is running, turn off
            if strcmp(app.liveTimerRot.Running, 'on')
               stop(app.liveTimerRot);
            end

            if strcmp(app.liveTimerTor.Running, 'on')
               stop(app.liveTimerTor);
            end

            % Creating cell matricies to save results to csv
            rotShowMatrix = [1];
            for i = 2:app.count
                rotShowMatrix = [rotShowMatrix, i];
            end
            rotTitles = {'Time (s)', 'Rotational Speed (rpm/sec)'};
            rotData = [0 rotShowMatrix; 0 app.rotYdata(1:app.count)]';
            rotPrint = [rotTitles; num2cell(rotData)];
            writecell(rotPrint, 'RPMResults.csv'); % Writing to csv
           
            torShowMatrix = [1];
            for i = 2:app.count
                torShowMatrix = [torShowMatrix, i];
            end
            torTitles = {'Time (s)', 'Torque (in-lb)'};
            torData = [0 torShowMatrix; 0 app.torYdata(1:app.count)]';
            torPrint = [torTitles; num2cell(torData)];
            writecell(torPrint, 'TorqueResults.csv'); % Writing to csv
        end

        % Value changed function: SetTorqueinlbSpinner
        function SetTorqueinlbSpinnerValueChanged(app, event)
            app.desiredTorquevalue = app.SetTorqueinlbSpinner.Value;
        end

        % Value changed function: MotorSwitch
        function MotorSwitchValueChanged(app, event)
            app.motorOnVal = app.MotorSwitch.Value;
            if strcmp(app.MotorSwitch.Value,'On')
                app.MotorStatusLamp.Color = 'green';
                app.SetRotationalSpeedRPMSpinner.Value = 0;
                app.SetTorqueinlbSpinner.Value = 0;
            else
                app.MotorStatusLamp.Color = 'red';
                app.SetRotationalSpeedRPMSpinner.Value = 0;
                app.SetTorqueinlbSpinner.Value = 0;
            end       
        end

        % Button pushed function: EmergencyMotorStopButton
        function EmergencyMotorStopButtonPushed(app, event)
            app.MotorSwitch.Value = 'Off';
            MotorSwitchValueChanged(app);
            app.SetTorqueinlbSpinner.Value = 16;
        end

        % Value changed function: MotorSelectionDropDown
        function MotorSelectionDropDownValueChanged(app, event)
            app.RValueEditField.Value = app.MotorSelectionDropDown.Value{1};
            app.kVValueEditField.Value = app.MotorSelectionDropDown.Value{2};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TorquePlot = uiaxes(app.UIFigure);
            title(app.TorquePlot, 'Torque (in-lb) vs. Time (sec)')
            xlabel(app.TorquePlot, 'Time (sec)')
            ylabel(app.TorquePlot, 'Torque (in-lb)')
            zlabel(app.TorquePlot, 'Z')
            app.RotationalSpeedPlot = uiaxes(app.UIFigure);
            title(app.RotationalSpeedPlot, 'Rotational Speed (RPM) vs. Time (sec)')
            xlabel(app.RotationalSpeedPlot, 'Time (sec)')
            ylabel(app.RotationalSpeedPlot, 'Rotational Speed (RPM)')
            zlabel(app.RotationalSpeedPlot, 'Z')
            app.SetRotationalSpeedRPMSpinnerLabel = uilabel(app.UIFigure);
            app.SetRotationalSpeedRPMSpinnerLabel.Text = 'Set Rotational Speed (RPM)';
            app.SetRotationalSpeedRPMSpinner = uispinner(app.UIFigure);
            app.SetRotationalSpeedRPMSpinner.ValueChangedFcn = createCallbackFcn(app, @SetRotationalSpeedRPMSpinnerValueChanged, true);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SetTorqueinlbSpinnerLabel = uilabel(app.UIFigure);
            app.SetTorqueinlbSpinnerLabel.Text = 'Set Torque  (in-lb)';
            app.SetTorqueinlbSpinner = uispinner(app.UIFigure);
            app.SetTorqueinlbSpinner.ValueChangedFcn = createCallbackFcn(app, @SetTorqueinlbSpinnerValueChanged, true);
            app.DataCollectionLabel = uilabel(app.UIFigure);
            app.DataCollectionLabel.Text = 'Data Collection';
            app.MeasuredRotationalSpeedRPMEditFieldLabel = uilabel(app.UIFigure);
            app.MeasuredRotationalSpeedRPMEditFieldLabel.Text = 'Measured Rotational Speed (RPM)';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.MeasuredRotationalSpeedRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeasuredTorqueinlbEditFieldLabel = uilabel(app.UIFigure);
            app.MeasuredTorqueinlbEditFieldLabel.Text = 'Measured Torque (in-lb)';
            app.MeasuredTorqueinlbEditField = uieditfield(app.UIFigure, 'numeric');
            app.MotorControlLabel = uilabel(app.UIFigure);
            app.MotorControlLabel.Text = 'Motor Control';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.TemperatureTable = uitable(app.UIFigure);
            app.TemperatureTable.ColumnName = {'Location'; 'Current Temperature (C)'; 'Safe Temperature (C)'};
            app.TemperatureTable.RowName = {};
            app.TemperatureMonitoringLabel_2 = uilabel(app.UIFigure);
            app.TemperatureMonitoringLabel_2.Text = 'Temperature Monitoring';
            app.MotorStatusLampLabel = uilabel(app.UIFigure);
            app.MotorStatusLampLabel.Text = 'Motor Status';
            app.MotorStatusLamp = uilamp(app.UIFigure);
            app.MotorSwitch = uiswitch(app.UIFigure, 'slider');
            app.MotorSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorSwitchValueChanged, true);
            app.EmergencyMotorStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyMotorStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyMotorStopButtonPushed, true);
            app.EmergencyMotorStopButton.Text = 'Emergency Motor Stop';
            app.MotorSelectionDropDownLabel = uilabel(app.UIFigure);
            app.MotorSelectionDropDownLabel.Text = 'Motor Selection';
            app.MotorSelectionDropDown = uidropdown(app.UIFigure);
            app.MotorSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorSelectionDropDownValueChanged, true);
            app.RValueEditFieldLabel = uilabel(app.UIFigure);
            app.RValueEditFieldLabel.Text = 'R Value';
            app.RValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.kVValueEditFieldLabel = uilabel(app.UIFigure);
            app.kVValueEditFieldLabel.Text = 'kV Value';
            app.kVValueEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dynoControllerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end