classdef dynoControllerGUI_noPID_savedInputs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InputWiperPositionEditField   matlab.ui.control.NumericEditField
        InputWiperPositionEditFieldLabel  matlab.ui.control.Label
        InputServoPositionEditField   matlab.ui.control.NumericEditField
        InputServoPositionEditFieldLabel  matlab.ui.control.Label
        kVValueEditField              matlab.ui.control.NumericEditField
        kVValueEditFieldLabel         matlab.ui.control.Label
        RValueEditField               matlab.ui.control.NumericEditField
        RValueEditFieldLabel          matlab.ui.control.Label
        MotorSelectionDropDown        matlab.ui.control.DropDown
        MotorSelectionDropDownLabel   matlab.ui.control.Label
        EmergencyMotorStopButton      matlab.ui.control.Button
        MotorSwitch                   matlab.ui.control.Switch
        MotorStatusLamp               matlab.ui.control.Lamp
        MotorStatusLampLabel          matlab.ui.control.Label
        TemperatureMonitoringLabel_2  matlab.ui.control.Label
        TemperatureTable              matlab.ui.control.Table
        StartButton                   matlab.ui.control.StateButton
        MotorControlLabel             matlab.ui.control.Label
        MeasuredTorqueinlbEditField   matlab.ui.control.NumericEditField
        MeasuredTorqueinlbEditFieldLabel  matlab.ui.control.Label
        MeasuredRotationalSpeedRPMEditField  matlab.ui.control.NumericEditField
        SaveDataButton                matlab.ui.control.Button
        MeasuredRotationalSpeedRPMEditFieldLabel  matlab.ui.control.Label
        OutputDataCollectionLabel     matlab.ui.control.Label
        SetWPinlbSpinner              matlab.ui.control.Spinner
        SetWPinlbSpinnerLabel         matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        SetServoPositionRPMSpinner    matlab.ui.control.Spinner
        SetServoPositionRPMSpinnerLabel  matlab.ui.control.Label
        RotationalSpeedPlot           matlab.ui.control.UIAxes
        TorquePlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %% General Variables
        a; % arduino variable
        s; % servo variable
        count; % counter
        DP; % digital potentiometer
        ADC1; % ADC 1 for torque
        ADC2; % ADC 2 for torque
        ADC3; % ADC 3 for torque
        dynamicExpand; % current size of plot to save data

        %% Rotational Variables
        liveTimerRot; % realtime variable for rotational speed
        rotPlot; % rotational speed plot
        rotYdata; % current rotational speed
        desiredRPMvalue; % desired RPM value
        rotPrintData; % rotational data to be printed
        currRPM; % current RPM
        servoAngle; % current servo angle
        dataListRot; % matrix for rotational speed filtering
        inputRotPrintData; % inputted rotational speed to be printed

        %% Torque Variables
        liveTimerTor; % realtime variable for torque
        torPlot; % torque plot
        torYdata; % current torque
        torPrintData; % torque data to be printed
        desiredTorquevalue; % desired RPM value
        dataListTor; % matrix for torque filtering
        torqueVal; % current torque from wiper position
        torqueCount; % checker where to start summation
        inputTorPrintData; % inputted torque to be printed

        %% Motor Variables
        startButtonVal; % checker for start button
        motorOnVal; % checker to prime motor
        motorProp; % motor properties
        currMotorR; % Current Selected Motor: R Value
        currMotorkV; % Current Selected Motor: kV Value
        motorVals; % Values of R and kV to display in char cell array

        %% Temperature Variables
        liveTimerTemp; % realtime temperature monitoring timer
        currTempVals; % current temperature on device
        safeTempVals; % safe temperature values
        headingLocation; % heading locations
    end

    methods (Access = private)

        % Function for real-time rotational speed measurement
        function rotTimerFcn(app,~,~)
            % For loop for data filtering
            for i = 1:length(app.dataListRot)
                app.dataListRot(i) = readVoltage(app.a, 'A0') / 5.0 * 15000; % reading analog line here for rotational speed
            end
            % takes mean from data read in for loop
            data = mean(app.dataListRot);

            app.currRPM = data; % Saves the current RPM as a global app variable

            app.count = app.count + 1; %Time variable incremented

            app.MeasuredRotationalSpeedRPMEditField.Value = data;
            app.InputServoPositionEditField.Value = app.SetServoPositionRPMSpinner.Value;

            % checker to see status of motor switch
            rpmCheck = 0;

            % checking motor value
            if (strcmp(app.MotorSwitch.Value, 'On'))
                rpmCheck = 1;
            end

            % Calculating rpm value based off voltage reading
            %app.servoAngle = app.RPM_control(rpmCheck, app.SetServoPositionRPMSpinner.Value, data, app.kVValueEditField.Value);

            % Writing Position to the Servo
            %writePosition(app.s, app.servoAngle);

            % Update YData in RPM plot

            % Double data output size if initial max reached
            if app.count == app.dynamicExpand
                app.rotPrintData(app.dynamicExpand:app.dynamicExpand*2) = 0;
                app.inputTorPrintData(app.dynamicExpand:app.dynamicExpand*2) = 0;
                app.dynamicExpand = app.dynamicExpand*2;
            end
            app.rotPrintData(app.count) = data;
            app.inputRotPrintData(app.count) = app.InputServoPositionEditField.Value;

            app.rotYdata = app.rotPlot.YData;
            app.rotYdata = circshift(app.rotYdata,1);
            app.rotYdata(1) = data;
            app.rotPlot.YData = app.rotYdata;

        end
        
        % Function for real-time torque measurement
        function torTimerFcn(app,~,~)
            % Reading torque measurement data here (for loop for data
            % filtering)
            for i = 1:length(app.dataListTor)
                % Reading ADC and performing necessary bitwise functions
                dataInComp = [1 14 226];
                dataInRead = [0];
                write(app.ADC1, dataInComp);
                write(app.ADC1, dataInRead);
                dataOut = read(app.ADC1, 2);
                dataOut = bitor(bitshift(dataOut(1), 8), dataOut(2));
                if (dataOut > 32767)
                    dataOut = -1*(65535-dataOut);
                end
                dataOut = dataOut/32767 * 0.256;
                app.dataListTor(i) = dataOut;
            end

            % taking mean of collected data
            dataOut = mean(app.dataListTor);

            % Converting mV signal to readable output torque
            data = app.torque_convert(dataOut * 1000);
    
            data = round(data,2);

            app.MeasuredTorqueinlbEditField.Value = data;
            app.InputWiperPositionEditField.Value = app.SetWPinlbSpinner.Value;

            % Outputs wanted wiper position
            %app.torqueVal = app.brake_control(1, app.SetWPinlbSpinner.Value, data, app.SetServoPositionRPMSpinner.Value, app.currRPM);

            % Writing wiper position to the arduino
            %writeRead(app.DP, [0 app.torqueVal]);

            % Update YData in RPM plot

            % Double data output size if initial max reached
            if app.count == app.dynamicExpand
                app.torPrintData(app.dynamicExpand:app.dynamicExpand*2) = 0;
                app.inputTorPrintData(app.dynamicExpand:app.dynamicExpand*2) = 0;
            end
            app.torPrintData(app.count) = data;
            app.inputTorPrintData(app.count) = app.InputWiperPositionEditField.Value;
            
            app.torYdata = app.torPlot.YData;
            app.torYdata = circshift(app.torYdata,1);
            app.torYdata(1) = data;
            app.torPlot.YData = app.torYdata;

        end
        
        % Function for temperature monitoring timer
        function tempTimerFcn(app,~,~)
            % Add data here
            dataInComp = [1 14 226];
            dataInRead = [0];
            write(app.ADC3, dataInComp);
            write(app.ADC3, dataInRead);
            dataOut = read(app.ADC3, 2);
            dataOut = bitor(bitshift(dataOut(1), 8), dataOut(2));
            if (dataOut > 32767)
                dataOut = -1*(65535-dataOut);
            end
            dataOut = dataOut/32767 * 0.256;
            tempLeft = dataOut*1000000/40 + 22;

            
            dataInComp = [1 62 226];
            dataInRead = [0];
            write(app.ADC3, dataInComp);
            write(app.ADC3, dataInRead);
            dataOut = read(app.ADC3, 2);
            dataOut = bitor(bitshift(dataOut(1), 8), dataOut(2));
            if (dataOut > 32767)
                dataOut = -1*(65535-dataOut);
            end
            dataOut = dataOut/32767 * 0.256;
            tempRight = dataOut*1000000/40 + 22;

            dataInComp = [1 14 226];
            dataInRead = [0];
            write(app.ADC1, dataInComp);
            write(app.ADC1, dataInRead);
            dataOut = read(app.ADC1, 2);
            dataOut = bitor(bitshift(dataOut(1), 8), dataOut(2));
            if (dataOut > 32767)
                dataOut = -1*(65535-dataOut);
            end
            dataOut = dataOut/32767 * 0.256;
            tempCenter = dataOut*1000000/40 + 22;

            %tempLeft = rand * 100;
            %tempCenter = round(rand * 100);
            %tempRight = round(rand * 100);

            app.currTempVals = {tempLeft, tempCenter, tempRight}';
            app.TemperatureTable.Data = table(app.headingLocation, app.currTempVals, app.safeTempVals);
        end

        % Function for input and output for rotational speed
        function servo_set = RPM_control(~,is_running, rpm_des, rpm_sens, KV)
            %   Function Documentation
            %
            %   [Include documentation]
            %
            %   Example:
            %
            %   Inputs:
            %
            %   Outputs:
            %
            %   Follows:
            %
            %                       _______________
            %            t_error   |               |   servo_set
            %           ---------->|    System     |-------------->
            %                      |_______________|
            %       Updated:
            %   Sam Kramer
            %   Feb 8th, 2023

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Loop Setup %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %{
    This section initiates the parameters to the function that will be
    necessary for its operation. The persistent function is the output
    memory of the system. This is in order to add the servo position
    adjustment to the previous servo position, it is stored in RAM and will
    need to be cleared before the beginning of each test.
        Last Updated:
    Sam Kramer
    Feb 8th, 2023
            %}

            % --Parameters
            persistent servo_out
            kP = .001;                         % Proportional gain
            kI = .0005;                         % Integral gain
            %kD = 1;                         % Derivative gain

            % --Other Parameters
            order = 3;                      % Order of output memory (time-steps)
            precision = 3;                  % Precision of the output signal
            volt = 14.8;                      % Motor input voltage (volts)
            max_rpm = KV * volt;            % Motor max rotational speed (RPM)
            servo_max = 1;                  % Max servo potentiometer position (ND)
            C = servo_max / max_rpm;        % Conversion factor (ND/RPM)


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Control Loop %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %{
        [Include documentation]
            %}

            if (is_running == true)

                % --Error in RPM (Feedback)
                e_rpm = rpm_des - rpm_sens;        % RPM error

                % --Calculate servo_adj from error (Multiply by plant)
                % Follows the P(s) = C/s where C is conversion factor
                servo_adj = C*kP*e_rpm + C*kI*sign(e_rpm)*(e_rpm^2);

                % --Saturate servo_adj to boundaries
                servo_adj = max(-0.025, servo_adj);    % Keeps above -0.25
                servo_adj = min(0.025, servo_adj);     % Keeps below +0.25 RPM

                % --Output new Wiper position by adding to previous WP value
                if (isempty(servo_out))

                    servo_set = servo_adj;           % Set first value

                else

                    servo_set = servo_out(1) + servo_adj;    % Add adj to prev value

                end

                % --Round to 3 decimal places and set servo_set boundaries
                servo_set = round(servo_set, precision,'decimals');
                servo_set = min(1, servo_set);
                servo_set = max(0.3001, servo_set);

                % --Store output of the loop in the WP_new vector
                servo_out = [servo_set, servo_out];

            elseif (is_running == false)

                % --Reset values once the test is complete
                servo_set = 0;
                servo_out = zeros(1, order);       % Reset WP_out variable

            end % Control loop end

        end % Function end
        
        % Function for to convert mv to mv/V torque output measurement
        function torque_sensed = torque_convert(~,sensor_data)
            % Function description:
            %
            %   This function will be used to calculate the torque that is being
            %   sensed from the reaction torque sensor. It will take in the mV/V data
            %   and then calculate the data to torque measurements in lb-in. This can
            %   handle both data vectors and single double data types. This uses linear
            %   interpolation to calculate the torque on the motor. The linear data is
            %   using the calibration data supplied with the sensor.
            %
            %   Example:
            %               torque_sensed = torque_convert(sensor_data)
            %
            %   Inputs:
            %           sensor_data -- This is the mV/V measurement that is being used
            %                          to calculate the torque. This is the direct
            %                          reading coming from the torque sensor. Can be an
            %                          array or a single integer.
            %
            %   Outputs:
            %           torque_sensed -- This is the sensed torque that has been
            %                            calculated by the function using the sensor
            %                            data collected by the torque sensor. Outputs a
            %                            measurement in lb-in data from -20 to 20
            %                            lb-in.
            %
            %   Function last updated:
            % Sam Kramer
            % 30 Jan, 2023

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % --Parameters
            excitation_voltage = 10;            % Excite Voltage applied to sensor
            M = [.101907 .101914];              % Slopes of mV to Torque graphs.
            B = [-0.00006817 -.000156699];      % Intercepts of linear torque slope


            % --Convert input signal(mV) to the mV/V range
            %{
        This part of the function will convert the mV sensed measurement to
        a mV/V measurement that can be linearly fit from the given
        calibration data from the company. Follows eaquation for conversion
        which is: mV/V = mV_signal / excitation voltage
            Last updated:
        Sam Kramer
        Jan 30, 2023
            %}
            mVV = sensor_data ./ excitation_voltage;    % Convert to mV/V

            % --Convert from the mV/V data to the torque measurement
            %{
        This part of the function linearly fits on to the calibration curve
        that was given when the sensor was purchased. If the sensor is 
        calibrated to mV/V_signal = torque*M + B then to find torque this 
        function will do (signal - B)/M = torque.
        
            Last Updated:
        Sam Kramer
        Jan 30, 2023
            %}
            conversion_factor = mean(M);        % mV to lb-in conversion factor
            adj = sign(sensor_data).*mean(B);   % Intercept of the linear relation
            torque_sensed = (mVV - adj) ./ conversion_factor;

        end

        % Function for torque control (brake control)
        function WP_set = brake_control(~,is_running, t_des, t_sens, rpm_des, rpm_sens)
            % Function Description:
            %
            %   This will be a function that will be used to control the current that
            %   is being run to the Hysteresis brake by changing the wiper position of
            %   the digital pontentiometer. To do this, a PI controller is used.
            %
            %   The wiper position of the system will not be adjusted until error_RPM
            %   reaches less than 5%, once this condition is met the brake will begin
            %   to increase torque by a maximum of .5 in-lbs. Once the motor RPM error
            %   reaches less than 2.5%, the control algorithm will begin to be more
            %   aggressive by adjusting by 1 in-lbs.
            %
            %   If the emergency stop button is pressed, the torque will increase to
            %   about 12 in-lbs in order to stop the motor instantly and then the user
            %   must turn off the test to reset the emergency stop.
            %
            %   Example:
            %           wiper_position = brake_control(is_running, t_des, t_sens, rpm_des, rpm_sens, emergency)
            %
            %   Inputs:
            %           is_running -- Boolean that tell system if the test has been
            %                         started
            %           t_des -- Desired torque to system
            %           t_sens -- Sensed torque on the system
            %           rpm_des -- Desired RPM of the test motor
            %           rpm_sens -- Sensed RPM of the test motor
            %           emergency -- Bool that tells system that emergency stop was
            %                        pressed
            %
            %   Outputs:
            %           wiper_position -- Wiper position of the digital potentiometer
            %
            %   Follows:
            %                   PI Controller, where we selected kP, kI = .1
            %                       _______________
            %            t_error   |               |   WP_set
            %           ---------->|    System     |-------------->
            %                      |_______________|
            %
            %       Last Updated
            %   Sam Kramer
            %   Feb 8th, 2023

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Loop Setup %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %{
    This section initiates the parameters to the function that will be
    necessary for its operation. The persistent function is the output
    memory of the system. This is in order to add the wiper position
    adjustment to the previous wiper position, it is stored in RAM and will
    need to be cleared before the beginning of each test. The kP and kI are
    the controller gains. The error in RPM is for the system to not start
    until there is a threshold in RPM error reached.
        Last Updated:
    Sam Kramer
    Feb 8th, 2023
            %}

            % --Control Parameters
            persistent WP_out
            kP = .5;                        % Proportional gain
            kI = .1;                        % Integral gain

            % --Physical parameters
            t_max = 16;                     % Max torque output (in-lbs)
            WP_max = 255;                   % Max wiper position (ND)
            C = WP_max / t_max;             % Conversion factor (ND/in-lbs)
            order = 3;                      % Order of output memory (time-steps)

            % --Error in RPM
            e_rpm = 100* (rpm_des - rpm_sens)/rpm_des;    % RPM error percentage

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%% Control loop %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %{
    This is the actual control function and it uses a feedback control
    method with a PI controller. The gains are set in the parameters. There
    are 3 if statements which cause the function not to run until the test
    begins and the rpm error reaches that critical threshold. In the case
    of an emergency stop, the emergency if statement is tripped and then
    the motor is cut. The last else statement will set the WP = 0. 
        Last Updated:
    Sam Kramer
    Feb 8, 2023
            %}

            % --If statement
            if (is_running == true)

                % --Calculate torque error and store it in WP_in
                t_error = t_des - t_sens;      % Calculate torque error

                % --Calculate WP_adj from error
                % Follows the P(s) = C/s where C is conversion factor
                WP_adj = (C*kI)*(t_error ^ 2)*sign(t_error) + kP*C*t_error;

                % --Saturate WP_adj to be within -25 and +25 WP output
                WP_adj = max(-8, WP_adj);      % Keeps above -48 WP
                WP_adj = min(8,WP_adj);        % Keeps below +48 WP

                % --Output new Wiper position by adding to previous WP value
                if (isempty(WP_out))

                    WP_set = WP_adj;                % Set first value

                else

                    WP_set = WP_out(1) + WP_adj;    % Add adj to prev value

                end

                % --Round and set boundaries
                WP_set = round(WP_set);         % Rounds to a whole int
                WP_set = min(255, WP_set);
                WP_set = max(0, WP_set);

                % --Store output of the loop in the WP_new vector
                WP_out = [WP_set, WP_out];

            else

                % --Reset values once the test is complete
                WP_set = 0;
                WP_out = zeros(1, order);       % Reset WP_out variable

            end     % Control loop end

        end     % Function end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% General Initialization Setup
            movegui(app.UIFigure,"center");

            % Arduino Setup
            app.a = arduino(); 
            app.s = servo(app.a, 'D2', 'MinPulseDuration', 1e-3, 'MaxPulseDuration', 2e-3); % setup to recognize motor as servo

            % ADC Setup
            app.ADC1 = device(app.a, 'I2CAddress', '0x48');
            app.ADC2 = device(app.a, 'I2CAddress', '0x49');
            app.ADC3 = device(app.a, 'I2CAddress', '0x4B');

            % Digital Poteniometer Setup
            app.DP = device(app.a, 'SPIChipSelectPin','D10'); 
            writeDigitalPin(app.a, 'D8', 0);
            writeDigitalPin(app.a, 'D9', 1);
            writeRead(app.DP, [0 0]);

            % General Variable Setup
            app.count = 0;
            app.dataListRot = zeros(1,5);
            app.dataListTor = zeros(1,5);
            app.torqueCount = 0;

            %% Live Plot Setup
            app.dynamicExpand = 60;
            app.rotPlot = plot(app.RotationalSpeedPlot, 0:60,zeros(1,61));
            app.torPlot = plot(app.TorquePlot, 0:60,zeros(1,61));

            app.rotPrintData = zeros(1, app.dynamicExpand);
            app.torPrintData = zeros(1, app.dynamicExpand);
            app.inputRotPrintData = zeros(1, app.dynamicExpand);
            app.inputTorPrintData = zeros(1, app.dynamicExpand);

            %% Timers Setup
            % Timer object for rotional speed plot
            app.liveTimerRot = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.rotTimerFcn);          % Specify callback function

            % Timer object for torque plot
            app.liveTimerTor = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.torTimerFcn);          % Specify callback function

            % Timer object for temperature monitoring
             app.liveTimerTemp = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 3, ...                     % Period is 3 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.tempTimerFcn);          % Specify callback function
            

            %% Temperature Monitoring Table Setup
            app.currTempVals = {0, 0, 0}'; % add real time temp values here
            app.safeTempVals = {50, 50, 50}'; % add safe temperatures values here
            app.headingLocation = {'Brake Left', 'Brake Center', 'Brake Right'}'; % add thermocouple locations here
            app.TemperatureTable.Data = table(app.headingLocation, app.currTempVals, app.safeTempVals);
            start(app.liveTimerTemp);

            %% Spinner Setup Settings
            app.SetServoPositionRPMSpinner.Step = 100;
            app.SetServoPositionRPMSpinner.Limits = [0 Inf];
            app.SetWPinlbSpinner.Step = 1;
            app.SetWPinlbSpinner.Limits = [0 Inf];
            
            %% Motor Priming Settings
            app.MotorStatusLamp.Color = 'red';
            writePosition(app.s, 0.3);
            app.servoAngle = 0;

            %% Reading in excel file data and activating save function
            app.motorProp = readtable('MotorProperties.xlsx'); % File must be named this and saved to same folder
        
            motorNameCell = table2cell(app.motorProp(:,1)); 
            app.currMotorR = table2array(app.motorProp(:,6));
            app.currMotorkV = table2array(app.motorProp(:,5));
            app.motorVals = {};
            
            % Adding R and kV Values to drop down menu
            for i = 1:numel(app.currMotorR)
                app.motorVals{i} = {app.currMotorR(i,1), app.currMotorkV(i,1)};
            end

            app.MotorSelectionDropDown.Items = motorNameCell';

            app.MotorSelectionDropDown.ItemsData = app.motorVals;
          
            % Updating inital Value of R and kV
            app.RValueEditField.Value = app.MotorSelectionDropDown.Value{1};
            app.kVValueEditField.Value = app.MotorSelectionDropDown.Value{2};        
           
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StartButton.Value = 0;
            stop(app.liveTimerRot);
            stop(app.liveTimerTor);
            stop(app.liveTimerTemp);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            app.startButtonVal = app.StartButton.Value;

            % If timers are not running, start it
            if strcmp(app.liveTimerRot.Running, 'off')
               start(app.liveTimerRot);
            end

            if strcmp(app.liveTimerTor.Running, 'off')
               start(app.liveTimerTor);
            end
            
            if strcmp(app.liveTimerTemp.Running, 'off')
               start(app.liveTimerTemp);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.liveTimerRot);
            delete(app.liveTimerRot);

            stop(app.liveTimerTor);
            delete(app.liveTimerTor);
            
            stop(app.liveTimerTemp);
            delete(app.liveTimerTemp);

            %% Resetting Control States
            writePosition(app.s, 0.2);
            writeRead(app.DP, [0 0]);
            
            delete(app)
        end

        % Value changed function: SetServoPositionRPMSpinner
        function SetServoPositionRPMSpinnerValueChanged(app, event)
            app.desiredRPMvalue = app.SetServoPositionRPMSpinner.Value; 
            writePosition(app.s, app.SetServoPositionRPMSpinner.Value);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % If timer is running, turn off
            if strcmp(app.liveTimerRot.Running, 'on')
               stop(app.liveTimerRot);
            end

            if strcmp(app.liveTimerTor.Running, 'on')
               stop(app.liveTimerTor);
            end

            if strcmp(app.liveTimerTemp.Running, 'on')
               stop(app.liveTimerTemp);
            end

            %% Creating cell matricies to save results to csv
            % Rotational Data Save
            rotShowMatrix = 1:1:app.dynamicExpand;
            rotTitles = {'Time (s)', 'Measured Rotational Speed (rpm/sec)', 'Inputted Servo Position'};
            rotData = [0 rotShowMatrix(1:app.count); 0 app.rotPrintData(1:app.count); 0 app.inputRotPrintData(1:app.count)]';
            rotPrint = [rotTitles; num2cell(rotData)];
            writecell(rotPrint, 'RPMResults.csv'); % Writing to csv
           
            % Torque Data Save
            torShowMatrix = 1:1:app.dynamicExpand;
            torTitles = {'Time (s)', 'Measured Torque (in-lb)', 'Inputted Wiper Position'};
%             disp(length(torShowMatrix));
%             disp(length(app.torPrintData));
%             disp(app.count);
            writematrix(app.inputTorPrintData(1:app.count), 'RawInputTorqueData.csv');
            writematrix(app.torPrintData(1:app.count), 'RawTorqueData.csv');
            torData = [0 torShowMatrix(1:app.count); 0 app.torPrintData(1:app.count); 0 app.inputTorPrintData(1:app.count)]';
            torPrint = [torTitles; num2cell(torData)];
            writecell(torPrint, 'TorqueResults.csv'); % Writing to csv
        end

        % Value changed function: SetWPinlbSpinner
        function SetWPinlbSpinnerValueChanged(app, event)
            app.desiredTorquevalue = app.SetWPinlbSpinner.Value;
            app.torqueCount = app.count;
            torqueValTestWiper = app.SetWPinlbSpinner.Value; % Add conversion from torque to wiper position here
            writeRead(app.DP, [0 torqueValTestWiper]);
        end

        % Value changed function: MotorSwitch
        function MotorSwitchValueChanged(app, event)
            app.motorOnVal = app.MotorSwitch.Value;
            if strcmp(app.MotorSwitch.Value,'On')
                app.MotorStatusLamp.Color = 'green';
                app.SetServoPositionRPMSpinner.Value = 0;
                app.SetWPinlbSpinner.Value = 0;
            else
                app.MotorStatusLamp.Color = 'red';
                app.SetServoPositionRPMSpinner.Value = 0;
                app.SetWPinlbSpinner.Value = 0;
                writePosition(app.s, 0.2);
                writeRead(app.DP, [0 0]);
            end       
        end

        % Button pushed function: EmergencyMotorStopButton
        function EmergencyMotorStopButtonPushed(app, event)
            app.MotorSwitch.Value = 'Off';
            MotorSwitchValueChanged(app);

            stop(app.liveTimerRot);
            stop(app.liveTimerTor);
            stop(app.liveTimerTemp);
        end

        % Value changed function: MotorSelectionDropDown
        function MotorSelectionDropDownValueChanged(app, event)
            app.RValueEditField.Value = app.MotorSelectionDropDown.Value{1};
            app.kVValueEditField.Value = app.MotorSelectionDropDown.Value{2};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TorquePlot = uiaxes(app.UIFigure);
            title(app.TorquePlot, 'Torque (in-lb) vs. Time (sec)')
            xlabel(app.TorquePlot, 'Time (sec)')
            ylabel(app.TorquePlot, 'Torque (in-lb)')
            zlabel(app.TorquePlot, 'Z')
            app.RotationalSpeedPlot = uiaxes(app.UIFigure);
            title(app.RotationalSpeedPlot, 'Rotational Speed (RPM) vs. Time (sec)')
            xlabel(app.RotationalSpeedPlot, 'Time (sec)')
            ylabel(app.RotationalSpeedPlot, 'Rotational Speed (RPM)')
            zlabel(app.RotationalSpeedPlot, 'Z')
            app.SetServoPositionRPMSpinnerLabel = uilabel(app.UIFigure);
            app.SetServoPositionRPMSpinnerLabel.Text = 'Set Servo Position (RPM)';
            app.SetServoPositionRPMSpinner = uispinner(app.UIFigure);
            app.SetServoPositionRPMSpinner.ValueChangedFcn = createCallbackFcn(app, @SetServoPositionRPMSpinnerValueChanged, true);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SetWPinlbSpinnerLabel = uilabel(app.UIFigure);
            app.SetWPinlbSpinnerLabel.Text = 'Set WP  (in-lb)';
            app.SetWPinlbSpinner = uispinner(app.UIFigure);
            app.SetWPinlbSpinner.ValueChangedFcn = createCallbackFcn(app, @SetWPinlbSpinnerValueChanged, true);
            app.OutputDataCollectionLabel = uilabel(app.UIFigure);
            app.OutputDataCollectionLabel.Text = 'Output Data Collection';
            app.MeasuredRotationalSpeedRPMEditFieldLabel = uilabel(app.UIFigure);
            app.MeasuredRotationalSpeedRPMEditFieldLabel.Text = 'Measured Rotational Speed (RPM)';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.MeasuredRotationalSpeedRPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeasuredTorqueinlbEditFieldLabel = uilabel(app.UIFigure);
            app.MeasuredTorqueinlbEditFieldLabel.Text = 'Measured Torque (in-lb)';
            app.MeasuredTorqueinlbEditField = uieditfield(app.UIFigure, 'numeric');
            app.MotorControlLabel = uilabel(app.UIFigure);
            app.MotorControlLabel.Text = 'Motor Control';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.TemperatureTable = uitable(app.UIFigure);
            app.TemperatureTable.ColumnName = {'Location'; 'Current Temperature (C)'; 'Safe Temperature (C)'};
            app.TemperatureTable.RowName = {};
            app.TemperatureMonitoringLabel_2 = uilabel(app.UIFigure);
            app.TemperatureMonitoringLabel_2.Text = 'Temperature Monitoring';
            app.MotorStatusLampLabel = uilabel(app.UIFigure);
            app.MotorStatusLampLabel.Text = 'Motor Status';
            app.MotorStatusLamp = uilamp(app.UIFigure);
            app.MotorSwitch = uiswitch(app.UIFigure, 'slider');
            app.MotorSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorSwitchValueChanged, true);
            app.EmergencyMotorStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyMotorStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyMotorStopButtonPushed, true);
            app.EmergencyMotorStopButton.Text = 'Emergency Motor Stop';
            app.MotorSelectionDropDownLabel = uilabel(app.UIFigure);
            app.MotorSelectionDropDownLabel.Text = 'Motor Selection';
            app.MotorSelectionDropDown = uidropdown(app.UIFigure);
            app.MotorSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorSelectionDropDownValueChanged, true);
            app.RValueEditFieldLabel = uilabel(app.UIFigure);
            app.RValueEditFieldLabel.Text = 'R Value';
            app.RValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.kVValueEditFieldLabel = uilabel(app.UIFigure);
            app.kVValueEditFieldLabel.Text = 'kV Value';
            app.kVValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputServoPositionEditFieldLabel = uilabel(app.UIFigure);
            app.InputServoPositionEditFieldLabel.Text = 'Input Servo Position';
            app.InputServoPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputWiperPositionEditFieldLabel = uilabel(app.UIFigure);
            app.InputWiperPositionEditFieldLabel.Text = 'Input Wiper Position';
            app.InputWiperPositionEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dynoControllerGUI_noPID_savedInputs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end