classdef motorTesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MeasuredRPMEditField       matlab.ui.control.NumericEditField
        MeasuredRPMEditFieldLabel  matlab.ui.control.Label
        ServoAngleEditField        matlab.ui.control.NumericEditField
        ServoAngleEditFieldLabel   matlab.ui.control.Label
        SliderValueEditField       matlab.ui.control.NumericEditField
        SliderValueEditFieldLabel  matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        SliderLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        a % arduino
        s % servo
        timerTemp;
    end
    
    methods (Access = private)
        
        function tempTimerFunc(app,~,~)
            % Add data here
            data = readVoltage(app.a, 'A0') / 5.0 * 15000; %Pulling dat
            app.MeasuredRPMEditField.Value = data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");
            app.Slider.Limits = [0 180];
            app.a = arduino();
            app.s = servo(app.a, 'D2', 'MinPulseDuration', 1e-3, 'MaxPulseDuration', 2e-3);

            % Timer object for torque plot
            app.timerTemp = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.tempTimerFunc);          % Specify callback function

            start(app.timerTemp);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.SliderValueEditField.Value = value;
            app.ServoAngleEditField.Value = app.Slider.Value/180;
            writePosition(app.s, app.ServoAngleEditField.Value);
        end

        % Value changed function: SliderValueEditField
        function SliderValueEditFieldValueChanged(app, event)
            app.Slider.Value = app.SliderValueEditField.Value;
            app.ServoAngleEditField.Value = app.SliderValueEditField.Value/180;
            writePosition(app.s, app.ServoAngleEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SliderValueEditFieldLabel = uilabel(app.UIFigure);
            app.SliderValueEditFieldLabel.Text = 'Slider Value';
            app.SliderValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.SliderValueEditField.ValueChangedFcn = createCallbackFcn(app, @SliderValueEditFieldValueChanged, true);
            app.ServoAngleEditFieldLabel = uilabel(app.UIFigure);
            app.ServoAngleEditFieldLabel.Text = 'Servo Angle';
            app.ServoAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeasuredRPMEditFieldLabel = uilabel(app.UIFigure);
            app.MeasuredRPMEditFieldLabel.Text = 'Measured RPM';
            app.MeasuredRPMEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = motorTesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end